classdef controlGiro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ConexinyControlPanel   matlab.ui.container.Panel
        PuertoDropDownLabel    matlab.ui.control.Label
        PuertoDropDown         matlab.ui.control.DropDown
        ConectarButton         matlab.ui.control.Button
        RealizarHomingButton   matlab.ui.control.StateButton
        MovernguloButton       matlab.ui.control.Button
        VolveraHOMEButton      matlab.ui.control.Button
        EjecutarSecuenciayGraficarButton  matlab.ui.control.Button
        AnguloEditFieldLabel   matlab.ui.control.Label
        AnguloEditField        matlab.ui.control.NumericEditField
        GraficadelEnsayoPanel  matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
        EstadoTextAreaLabel    matlab.ui.control.Label
        EstadoTextArea         matlab.ui.control.TextArea
    end


    properties (Access = private)
        robotDevice   % Objeto para la conexión con el robot
        isConnected = false; % Bandera para saber si estamos conectados
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Llenar la lista de puertos COM disponibles al iniciar
            app.PuertoDropDown.Items = seriallist;
            app.EstadoTextArea.Value = {'Listo para conectar.'};
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            if ~app.isConnected
                comPort = app.PuertoDropDown.Value;
                try
                    % SINTAXIS LEGACY para crear y configurar el objeto serie
                    app.robotDevice = serial(comPort, 'BaudRate', 115200, 'Terminator', 'LF');
                    fopen(app.robotDevice); % Abrir el puerto
                    
                    fscanf(app.robotDevice); % Leer y descartar el mensaje de bienvenida
                    
                    app.EstadoTextArea.Value = {['Conectado a ' comPort]};
                    app.ConectarButton.Text = 'Desconectar';
                    app.isConnected = true;
                catch ME
                    app.EstadoTextArea.Value = {'Error al conectar:', ME.message};
                end
            else
                fclose(app.robotDevice); % Cerrar el puerto
                delete(app.robotDevice); % Borrar el objeto
                app.EstadoTextArea.Value = {'Desconectado.'};
                app.ConectarButton.Text = 'Conectar';
                app.isConnected = false;
            end
        end

        % Value changed function: RealizarHomingButton
        function RealizarHomingButtonValueChanged(app, event)
            if ~app.isConnected, app.EstadoTextArea.Value{end+1} = 'Error: No está conectado.'; return; end
            
            fprintf(app.robotDevice, 'h'); % SINTAXIS LEGACY para escribir
            app.EstadoTextArea.Value{end+1} = 'Comando Homing enviado...';
            pause(0.5);
            
            while app.robotDevice.BytesAvailable > 0
                response = fscanf(app.robotDevice); % SINTAXIS LEGACY para leer
                app.EstadoTextArea.Value{end+1} = strtrim(response);
            end
        end

        % Button pushed function: MovernguloButton
        function MovernguloButtonPushed(app, event)
            if ~app.isConnected, app.EstadoTextArea.Value{end+1} = 'Error: No está conectado.'; return; end
            
            angulo = app.AnguloEditField.Value;
            comando = sprintf("M%.2f", angulo);
            
            fprintf(app.robotDevice, comando);
            
            app.EstadoTextArea.Value{end+1} = strtrim(fscanf(app.robotDevice));
            app.EstadoTextArea.Value{end+1} = strtrim(fscanf(app.robotDevice));
        end

        % Button pushed function: VolveraHOMEButton
        function VolveraHOMEButtonPushed(app, event)
            if ~app.isConnected, app.EstadoTextArea.Value{end+1} = 'Error: No está conectado.'; return; end
            
            fprintf(app.robotDevice, 'HOME');
            
            app.EstadoTextArea.Value{end+1} = strtrim(fscanf(app.robotDevice));
            app.EstadoTextArea.Value{end+1} = strtrim(fscanf(app.robotDevice));
        end

        % Button pushed function: EjecutarSecuenciayGraficarButton
        function EjecutarSecuenciayGraficarButtonPushed(app, event)
            if ~app.isConnected, app.EstadoTextArea.Value{end+1} = 'Error: No está conectado.'; return; end
            
            cla(app.UIAxes); %Limpia la gráfica anterior para empezar de cero.
            app.EstadoTextArea.Value = {'--- Iniciando Secuencia de Ensayo ---'};
            drawnow;
            
            fprintf(app.robotDevice, 'START');
            app.EstadoTextArea.Value{end+1} = strtrim(fscanf(app.robotDevice));
            
            secuencia_angulos = [45, -45, 90, -90, 0];
            for i = 1:length(secuencia_angulos)
                comando = sprintf("M%.2f", secuencia_angulos(i));
                fprintf(app.robotDevice, comando);
                app.EstadoTextArea.Value{end+1} = strtrim(fscanf(app.robotDevice));
                app.EstadoTextArea.Value{end+1} = strtrim(fscanf(app.robotDevice));
                drawnow;
            end
            
            app.EstadoTextArea.Value{end+1} = 'Secuencia finalizada. Pidiendo datos...';
            fprintf(app.robotDevice, 'GETDATA');
            
            dataBlock = "";
            fscanf(app.robotDevice); % Descartar "--- INICIO_DATOS ---"
            
            while true
                linea = fscanf(app.robotDevice);
                if contains(linea, "--- FIN_DATOS ---"), break; end
                dataBlock = [dataBlock; linea];
            end
            
            app.EstadoTextArea.Value{end+1} = 'Datos recibidos. Graficando...';
            
            try
                dataMatrix = str2num(char(dataBlock));
                tiempo = dataMatrix(:,1) / 1000;
                angulo = dataMatrix(:,2);
                
                plot(app.UIAxes, tiempo, angulo, '-o', 'LineWidth', 1.5, 'MarkerSize', 4);
                grid(app.UIAxes, 'on');
                xlabel(app.UIAxes, 'Tiempo (s)');
                ylabel(app.UIAxes, 'Ángulo del Eje (grados)');
                title(app.UIAxes, 'Trayectoria del Ensayo');
            catch ME
                app.EstadoTextArea.Value{end+1} = 'Error al procesar los datos recibidos.';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ConexinyControlPanel = uipanel(app.UIFigure);
            app.ConexinyControlPanel.Title = 'Conexión y Control';
            app.PuertoDropDownLabel = uilabel(app.ConexinyControlPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.PuertoDropDown = uidropdown(app.ConexinyControlPanel);
            app.ConectarButton = uibutton(app.ConexinyControlPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.RealizarHomingButton = uibutton(app.ConexinyControlPanel, 'state');
            app.RealizarHomingButton.ValueChangedFcn = createCallbackFcn(app, @RealizarHomingButtonValueChanged, true);
            app.RealizarHomingButton.Text = 'Realizar Homing';
            app.MovernguloButton = uibutton(app.ConexinyControlPanel, 'push');
            app.MovernguloButton.ButtonPushedFcn = createCallbackFcn(app, @MovernguloButtonPushed, true);
            app.MovernguloButton.Text = 'Mover ángulo';
            app.VolveraHOMEButton = uibutton(app.ConexinyControlPanel, 'push');
            app.VolveraHOMEButton.ButtonPushedFcn = createCallbackFcn(app, @VolveraHOMEButtonPushed, true);
            app.VolveraHOMEButton.Text = 'Volver a HOME';
            app.EjecutarSecuenciayGraficarButton = uibutton(app.ConexinyControlPanel, 'push');
            app.EjecutarSecuenciayGraficarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarSecuenciayGraficarButtonPushed, true);
            app.EjecutarSecuenciayGraficarButton.Text = 'Ejecutar Secuencia y Graficar';
            app.AnguloEditFieldLabel = uilabel(app.ConexinyControlPanel);
            app.AnguloEditFieldLabel.Text = 'Angulo';
            app.AnguloEditField = uieditfield(app.ConexinyControlPanel, 'numeric');
            app.GraficadelEnsayoPanel = uipanel(app.UIFigure);
            app.GraficadelEnsayoPanel.Title = 'Grafica del Ensayo';
            app.UIAxes = uiaxes(app.GraficadelEnsayoPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.EstadoTextAreaLabel = uilabel(app.UIFigure);
            app.EstadoTextAreaLabel.Text = 'Estado';
            app.EstadoTextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = controlGiro

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end